classdef HSC_Result_compiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        TablePlotting                   matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        SelectyourdatafileLabel         matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        JVcurvesPanel                   matlab.ui.container.Panel
        ShowlegendinplotsCheckBox       matlab.ui.control.CheckBox
        IncludedarkJVCheckBox           matlab.ui.control.CheckBox
        PlotbestcurvesofeachtypeCheckBox  matlab.ui.control.CheckBox
        BoxplotPanel                    matlab.ui.container.Panel
        bothresultswillalsobeincludedinboxplotLabel  matlab.ui.control.Label
        IfIncludesweep2resultsisonLabel  matlab.ui.control.Label
        DrawboxplotsofPVparametersCheckBox  matlab.ui.control.CheckBox
        DatatablePanel                  matlab.ui.container.Panel
        IncluderesultsfromButtonGroup   matlab.ui.container.ButtonGroup
        bothsweepsButton                matlab.ui.control.RadioButton
        sweep2Button                    matlab.ui.control.RadioButton
        sweep1Button                    matlab.ui.control.RadioButton
        CalculateaveragesattheendoftableCheckBox  matlab.ui.control.CheckBox
        DisplayfullsamplenamesCheckBox  matlab.ui.control.CheckBox
        Resultcompilerv52Label          matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        AuthorPaavoMkinenupdated21022025MATLAB2023bLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UITable                         matlab.ui.control.Table
        ContextMenu                     matlab.ui.container.ContextMenu
        ExporttocsvMenu                 matlab.ui.container.Menu
        ExporttoxslxMenu                matlab.ui.container.Menu
        CancelMenu                      matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Properties to operate the calculations
    properties (Access = private)
        vars % List of data variables in workspace, i.e. the list of entry names in the result file
        rfilepath % result file path
        rfilename % name of the file containing the measurement results to be assessed
        extension % result file extension, aka. type
        sfilenames % cell containing chosen filenames for stability processing
        sfilepath % stability file path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [app.rfilename, app.rfilepath] = uigetfile('*.*', 'Select the file(s) containing your data', 'MultiSelect','on');
            [~,~,app.extension] = fileparts(fullfile(app.rfilepath,app.rfilename));

            if isequal (app.rfilename, 0)
                app.Label.Text = 'User cancelled selection';
                app.UpdateButton.Enable = 'off';
            elseif strcmp(app.extension, '.json')
                app.Label.Text = app.rfilename;
                app.UpdateButton.Enable = 'on';
            elseif isa(app.rfilename, "cell")
                app.Label.Text = 'Multiselection only supports .json-format';
                app.UpdateButton.Enable = 'off';
            else
                app.Label.Text = app.rfilename;
                app.UpdateButton.Enable = 'on';
                app.vars = who('-file', fullfile(app.rfilepath,app.rfilename));
            end

            figure(app.UIFigure)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.Image.Visible = 'off';
            app.AuthorPaavoMkinenupdated21022025MATLAB2023bLabel.Visible = 'off';
            
            rowcount = 0;
            rarray = cell(1, 8);
            sweepstorage = cell(1, 5);

            if strcmp(app.extension, '.json') % Table for .json-files
                for h = 1:length(app.rfilename) % This would loop for the letters of a single file, is broken further down in that case
                    if isa(app.rfilename, "char")
                        str = fileread(fullfile(app.rfilepath,app.rfilename));
                    else
                        str = fileread(fullfile(app.rfilepath,app.rfilename{h}));
                    end
                    data = jsondecode(str); % Using the jsondecode function to parse JSON from string 
                    
                    for i = 1:length(data.devices)  % Loop through all the devices stored in data
                        pixel_name = data.devices(i).name;
                        exp_list = data.devices(i).experiments;
                        
                        L_counter = 0;
                        D_counter = 0;

                        for j = 1:length(exp_list)  % Loop through experiments done to a device
                            experiment = exp_list(j);
                            
                            if strcmp(experiment.type, 'Perform parallel JV') && any(experiment.parameters(end).data) % Ensure the correct measurement type, and check that these are illuminated sweeps.
                                L_counter = L_counter+1;
                                
                                if isequal(app.DisplayfullsamplenamesCheckBox.Value, 1)
                                    exp_name = pixel_name + "m" + num2str(L_counter);    % add more info to the name
                                else
                                    exp_name = pixel_name;
                                end
                                % name the sweeps
                                s1_name = exp_name + "_sweep1"; s2_name = exp_name + "_sweep2";
                                % convert str to char so that they can be added to the table
                                exp_name = char(exp_name); s1_name = char(s1_name); s2_name = char(s2_name);

                                PCE = experiment.results(10).data;  % Turn the data into variables
                                FF = experiment.results(8).data;
                                Jsc = abs(experiment.results(7).data);
                                Voc = experiment.results(6).data;
                                area = data.devices(i).parameters(3).data;
                                hyst = experiment.results(9).data;  % Litoslite gives this in percent, positive means sweep1 was better
                                LampP = experiment.parameters(11).data;

                                volt = experiment.results(1).data;  % Gather the sweep data points to variables
                                currde = experiment.results(2).data;
                                
                                if isequal(app.bothsweepsButton.Value, 1)   % Determine which sweeps to display
                                    rowcount = rowcount+1;
                                    rarray(rowcount,:) = {s1_name, PCE(1), FF(1), Jsc(1), Voc(1), hyst, area, LampP(1)};  % Add the data to the result array
                                    rowcount = rowcount+1;
                                    rarray(rowcount,:) = {s2_name, PCE(2), FF(2), Jsc(2), Voc(2), hyst, area, LampP(1)};
                                elseif isequal(app.sweep1Button.Value, 1)
                                    rowcount = rowcount+1;
                                    if isequal(app.DisplayfullsamplenamesCheckBox.Value, 1)
                                        rarray(rowcount,:) = {s1_name, PCE(1), FF(1), Jsc(1), Voc(1), hyst, area, LampP(1)};
                                    else
                                        rarray(rowcount,:) = {exp_name, PCE(1), FF(1), Jsc(1), Voc(1), hyst, area, LampP(1)};
                                    end
                                elseif isequal(app.sweep2Button.Value, 1)
                                    rowcount = rowcount+1;
                                    if isequal(app.DisplayfullsamplenamesCheckBox.Value, 1)
                                        rarray(rowcount,:) = {s2_name, PCE(2), FF(2), Jsc(2), Voc(2), hyst, area, LampP(1)};
                                    else
                                        rarray(rowcount,:) = {exp_name, PCE(2), FF(2), Jsc(2), Voc(2), hyst, area, LampP(1)};
                                    end
                                else
                                    app.Label.Text = {'Data extraction error:', 'Sweep selection'};
                                end
                                
                                if isequal(app.bothsweepsButton.Value, 1)
                                    sweepstorage(rowcount/2,1:3) = {exp_name, volt, currde};    %  Halve the rowcount to prevent duplicates
                                else
                                    sweepstorage(rowcount,1:3) = {exp_name, volt, currde};    % Store sweep data in a cell array
                                end

                            elseif strcmp(experiment.type, 'Perform parallel JV')    % Append dark sweeps to sweepstorage.
                                D_counter = D_counter+1;
                                
                                if isequal(app.DisplayfullsamplenamesCheckBox.Value, 1)
                                    exp_name = pixel_name + "m" + num2str(D_counter);    % add more info to the name
                                else
                                    exp_name = pixel_name;
                                end
                                % name the sweeps
                                s1_name = exp_name + "_sweep1"; s2_name = exp_name + "_sweep2";
                                % convert str to char so that they can be added to the table
                                exp_name = char(exp_name); s1_name = char(s1_name); s2_name = char(s2_name);

                                volt = experiment.results(1).data;
                                currde = experiment.results(2).data;
                                if L_counter >= D_counter
                                    if isequal(app.bothsweepsButton.Value, 1)
                                        sweepstorage(rowcount/2,4:5) = {volt, currde};
                                        sweepstorage(rowcount/2,1) = {exp_name};
                                    else
                                        sweepstorage(rowcount,4:5) = {volt, currde};
                                        sweepstorage(rowcount,1) = {exp_name};
                                    end
                                else
                                    if isequal(app.bothsweepsButton.Value, 1)
                                        rowcount = rowcount+2;
                                        sweepstorage(rowcount/2,4:5) = {volt, currde};
                                        sweepstorage(rowcount/2,1) = {exp_name};
                                        rowcount = rowcount-2;
                                    else
                                        rowcount = rowcount+1;
                                        sweepstorage(rowcount,4:5) = {volt, currde};
                                        sweepstorage(rowcount,1) = {exp_name};
                                        rowcount = rowcount-1;
                                    end
                                end
                            end
                        end
                    end
                    if isa(app.rfilename, "char")
                        break
                    end
                end
                sweepstorage = sortrows(sweepstorage, 1); % Sort the sweepstorage alphabetically.

            else % Table for .mat-files
                rfile = load(fullfile(app.rfilepath,app.rfilename));
                rcellist = struct2cell(rfile);
                
                NoLamp = 0;

                for i = 1:numel(app.vars)
                    varname = strsplit(app.vars{i}, '_');
                    if any(strcmp(varname, 'FoM')) && ~any(strcmp(varname, 'D')) && ~any(strcmp(varname, 'd'))
                        %%%%%
                        if isequal(app.bothsweepsButton.Value, 1)
                            rowcount = rowcount+1;
                            
                            rcells = rcellist{i, 1};
                            cells = rcells(2, :);
                            
                            if isequal(app.DisplayfullsamplenamesCheckBox.Value, 1)
                                rarray(rowcount,1) = app.vars(i);
                            else
                                rarray(rowcount,1) = cellstr(append(varname{1}, '_', varname{2}, '_', varname{end}));
                            end
                            rarray(rowcount,2:5) = cells(1:4);
                            rarray(rowcount,7) = cells(5);
                            try
                                rarray(rowcount,8) = cells(6);
                            catch
                                NoLamp = 1;
                            end
    
                            if any(strcmp(varname, 's1'))
                                rcells2 = rcellist{i+1, 1};
                                sweep2cells = rcells2(2, :);
                                if isa(sweep2cells, 'cell')
                                    Hindex = (cells{1}/sweep2cells{1}-1)*100;
                                    rarray(rowcount,6) = num2cell(Hindex);
                                end
                            end
                        %%%%%
                        elseif isequal(app.sweep1Button.Value, 1) && any(strcmp(varname, 's1'))
                            rowcount = rowcount+1;
                            
                            rcells1 = rcellist{i, 1};
                            rcells2 = rcellist{i+1, 1};
                            sweep1cells = rcells1(2, :);
                            sweep2cells = rcells2(2, :);
                            
                            fullcells(1:4) = sweep1cells(1:4);
                            fullcells(6) = sweep1cells(5);
                            try
                                fullcells(7) = sweep1cells(6);
                            catch
                                NoLamp = 1;
                            end

                            if isa(sweep2cells, 'cell')
                                Hindex = (sweep1cells{1}/sweep2cells{1}-1)*100;
                                fullcells(5) = num2cell(Hindex);   
                            else
                                fullcells(5) = {''};
                            end
                            
                            if NoLamp
                                rarray(rowcount,2:7) = fullcells;
                            else
                                rarray(rowcount,2:8) = fullcells;
                            end
    
                            if isequal(app.DisplayfullsamplenamesCheckBox.Value, 1)
                                rarray(rowcount,1) = app.vars(i);
                            else
                                rarray(rowcount,1) = cellstr(append(varname{1}, '_', varname{2}));
                            end
                        %%%%%
                        elseif isequal(app.sweep2Button.Value, 1) && any(strcmp(varname, 's2'))
                            rowcount = rowcount+1;
                            
                            rcells1 = rcellist{i-1, 1};
                            rcells2 = rcellist{i, 1};
                            sweep1cells = rcells1(2, :);
                            sweep2cells = rcells2(2, :);
                            
                            fullcells(1:4) = sweep2cells(1:4);
                            fullcells(6) = sweep2cells(5);
                            try
                                fullcells(7) = sweep2cells(6);
                            catch
                                NoLamp = 1;
                            end
    
                            if isa(sweep1cells, 'cell')
                                Hindex = (sweep1cells{1}/sweep2cells{1}-1)*100;
                                fullcells(5) = num2cell(Hindex);   
                            else
                                fullcells(5) = {''};
                            end

                            if NoLamp
                                rarray(rowcount,2:7) = fullcells;
                            else
                                rarray(rowcount,2:8) = fullcells;
                            end

                            if isequal(app.DisplayfullsamplenamesCheckBox.Value, 1)
                                rarray(rowcount,1) = app.vars(i);
                            else
                                rarray(rowcount,1) = cellstr(append(varname{1}, '_', varname{2}));
                            end
                        %%%%%
                        end
                    end 
                end
            end
            
            rarray = sortrows(rarray,1);  % Sort the rows alphabetically based on sample names. This prevents JV-curve plotting from breaking further down.
            app.UITable.Data = rarray;  % Update the table displayed to user.

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Categorizing results
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % Separate results
            labels = {};
            PCE = [];
            FF = [];
            Isc =[];
            Voc = [];
            Hi = [];
            Area = [];
            Plamp = [];
        
            Clist =[[0 0.4470 0.7410];[0.8500 0.3250 0.0980];[0.9290 0.6940 0.1250];[0.4940 0.1840 0.5560];[0.4660 0.6740 0.1880];[0.3010 0.7450 0.9330];[0.6350 0.0780 0.1840];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0];[0 0 0]];
        
            for j = 1:size(rarray,1)
                sampleid = strsplit(rarray{j,1}, '_');
                labels(j) = cellstr(sampleid{1});
                PCE(j) = rarray{j,2};
                FF(j) = rarray{j,3};
                Isc(j) = rarray{j,4};
                Voc(j) = rarray{j,5};
                try
                    Hi(j) = rarray{j,6};
                catch
                    Hi(j) = 0;
                end
                Area(j) = rarray{j,7};
                try
                    Plamp(j) = rarray{j,8};
                catch
                    Plamp(j) = 0;
                end
            end
            
            labels = categorical(labels);
            indexes = grp2idx(labels);
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Boxplot
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if isequal(app.DrawboxplotsofPVparametersCheckBox.Value, 1) 
                warning('off','MATLAB:handle_graphics:Layout:NoPositionSetInTiledChartLayout')
                % Power conversion efficiency plot:
                figure('Name', 'PCE boxplot', 'DefaultAxesFontSize', 18, 'Position',[10 10 700 500])
                boxplot(PCE, labels, 'Widths',0.8, 'Colors',Clist, 'Symbol','')
                h = findobj(gca,'Tag','Box');
                for j=1:length(h)
                    patch(get(h(j),'XData'),get(h(j),'YData'),Clist(length(h)+1-j,:),'FaceAlpha',0.1,'EdgeColor',Clist(length(h)+1-j,:));
                end
                hold on
                ylabel('PCE (%)')
                for n=1:max(unique(indexes))
                    hs = scatter(ones(sum(indexes==n),1) + n-1, PCE(indexes == n), 50, "filled",'jitter','on','JitterAmount',0.38);
                    %hs.Marker = Mlist(n);
                    hs.MarkerFaceAlpha = 0.8;
                    hs.MarkerFaceColor = Clist(n,:);
                end
                hold off
                
                % Stacked plot for the rest:
                figure('Name', 'FF, Isc, & Voc boxplot', 'DefaultAxesFontSize',18, 'Position',[10 10 700 700])
                t = tiledlayout(3,1);
                
                % FF
                ax1 = nexttile;
                boxplot(FF, labels, 'Widths',0.8, 'Colors',Clist, 'Symbol','')
                h = findobj(gca,'Tag','Box');
                for j=1:length(h)
                    patch(get(h(j),'XData'),get(h(j),'YData'),Clist(length(h)+1-j,:),'FaceAlpha',0.1,'EdgeColor',Clist(length(h)+1-j,:));
                end
                hold on
                ylabel('FF (%)')
                for n=1:max(unique(indexes))
                    hs = scatter(ones(sum(indexes==n),1) + n-1, FF(indexes == n),"filled",'jitter','on','JitterAmount',0.38);
                    %hs.Marker = Mlist(n);
                    hs.MarkerFaceAlpha = 0.8;
                    hs.MarkerFaceColor = Clist(n,:);
                end
                hold off
                
                % Isc
                ax2 = nexttile;
                boxplot(Isc, labels, 'Widths',0.8, 'Colors',Clist, 'Symbol','')
                h = findobj(gca,'Tag','Box');
                for j=1:length(h)
                    patch(get(h(j),'XData'),get(h(j),'YData'),Clist(length(h)+1-j,:),'FaceAlpha',0.1,'EdgeColor',Clist(length(h)+1-j,:));
                end
                hold on
                ylabel('J_{sc} (mA/cm^2)')
                for n=1:max(unique(indexes))
                    hs = scatter(ones(sum(indexes==n),1) + n-1, Isc(indexes == n),"filled",'jitter','on','JitterAmount',0.38);
                    %hs.Marker = Mlist(n);
                    hs.MarkerFaceAlpha = 0.8;
                    hs.MarkerFaceColor = Clist(n,:);
                end
                hold off
                
                % Voc
                ax3 = nexttile;
                boxplot(Voc, labels, 'Widths',0.8, 'Colors',Clist, 'Symbol','')
                h = findobj(gca,'Tag','Box');
                for j=1:length(h)
                    patch(get(h(j),'XData'),get(h(j),'YData'),Clist(length(h)+1-j,:),'FaceAlpha',0.1,'EdgeColor',Clist(length(h)+1-j,:));
                end
                hold on
                ylabel('V_{oc} (V)')
                for n=1:max(unique(indexes))
                    hs = scatter(ones(sum(indexes==n),1) + n-1, Voc(indexes == n),"filled",'jitter','on','JitterAmount',0.38);
                    %hs.Marker = Mlist(n);
                    hs.MarkerFaceAlpha = 0.8;
                    hs.MarkerFaceColor = Clist(n,:);
                end
                hold off
                
                % Linking and axes
                linkaxes([ax1 ax2 ax3],'x')
                xticklabels(ax1,{})
                xticklabels(ax2,{})
                t.TileSpacing = 'compact';
                
                warning('on','MATLAB:handle_graphics:Layout:NoPositionSetInTiledChartLayout')
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% J-V curve printing
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if isequal(app.PlotbestcurvesofeachtypeCheckBox.Value, 1) 
                bestrarrayind = [];
                currentbest = PCE(1);
                previndex = 1;
                bestindex = 0;

                for i=1:length(PCE)
                    if ~isequal(indexes(i), previndex) || isequal(i, length(PCE))
                        bestrarrayind(previndex) = bestindex;
                        previndex = indexes(i);
                        currentbest = PCE(i);
                        bestindex = i;
                    elseif PCE(i) >= currentbest
                        currentbest = PCE(i);
                        bestindex = i;
                    end
                end

                for i=1:length(bestrarrayind)
                    full_resultname = strsplit(rarray{bestrarrayind(i),1}, '_');
                    resultname = append(full_resultname{1}, '_', full_resultname{2});

                    figure('Name', resultname, 'DefaultAxesFontSize', 16, 'Position',[10 10 700 500])
                    hold on
                    ylabel('Current density (mA/cm^2)')
                    xlabel('Voltage (V)')
                    box on

                    legend_labels = cell(1, 1);
                    label_counter = 0;
                    
                    if strcmp(app.extension, '.json')
                        if isequal(app.bothsweepsButton.Value, 1)
                            plot(sweepstorage{round(bestrarrayind(i)/2),2}, sweepstorage{round(bestrarrayind(i)/2),3}.*1000)
                            label_counter = label_counter+1;
                            legend_labels{label_counter} = sweepstorage{round(bestrarrayind(i)/2),1} + "_L";
                            if isequal(app.IncludedarkJVCheckBox.Value, 1)
                                try
                                    plot(sweepstorage{round(bestrarrayind(i)/2),4}, sweepstorage{round(bestrarrayind(i)/2),5}.*1000)
                                    label_counter = label_counter+1;
                                    legend_labels{label_counter} = sweepstorage{round(bestrarrayind(i)/2),1} + "_D";
                                catch
                                    app.Label.Text = {'Sweep plotting error:', 'Dark measurement not found'};
                                end
                            end
                        else
                            plot(sweepstorage{bestrarrayind(i),2}, sweepstorage{bestrarrayind(i),3}.*1000)
                            label_counter = label_counter+1;
                            legend_labels{label_counter} = sweepstorage{bestrarrayind(i),1} + "_L";
                            if isequal(app.IncludedarkJVCheckBox.Value, 1)
                                try
                                    plot(sweepstorage{bestrarrayind(i),4}, sweepstorage{bestrarrayind(i),5}.*1000)
                                    label_counter = label_counter+1;
                                    legend_labels{label_counter} = sweepstorage{bestrarrayind(i),1} + "_D";
                                catch
                                    app.Label.Text = {'Sweep plotting error:', 'Dark measurement not found'};
                                end
                            end
                        end
                    else
                        for n=1:numel(app.vars)
                            if contains(app.vars{n,1}, resultname) && ~contains(app.vars{n,1}, 'FoM')
                                if  ~contains(app.vars{n,1}, '_D_') && ~contains(app.vars{n,1}, '_d_')
                                    plot(rcellist{n,1}(:,1),rcellist{n,1}(:,2).*100000./rarray{bestrarrayind(i),6})
                                    label_counter = label_counter+1;
                                    legend_labels{label_counter} = app.vars{n,1};
                                elseif isequal(app.IncludedarkJVCheckBox.Value, 1)
                                    plot(rcellist{n,1}(:,1),rcellist{n,1}(:,2).*100000./rarray{bestrarrayind(i),6})
                                    label_counter = label_counter+1;
                                    legend_labels{label_counter} = app.vars{n,1};
                                end
                            end
                        end
                    end
                    yline(0)

                    if isequal(app.ShowlegendinplotsCheckBox.Value, 1)
                        leg = legend(legend_labels, 'Location', 'best', 'FontSize', 10);
                        legend boxoff
                        set(leg, 'Interpreter', 'none')
                    end

                    ax = gca; % current axes
                    ax.XLim = [-inf inf];
                    if isequal(app.IncludedarkJVCheckBox.Value, 0)
                        ylim('tight')
                        ylimits = ylim;
                        newlimits(1) = ylimits(1)*1.2;
                        newlimits(2) = ylimits(1)*(-0.1);
                        ax.YLim = newlimits;
                    end

                    hold off
                end
            end
             
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Average calculator
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if isequal(app.CalculateaveragesattheendoftableCheckBox.Value, 1)

                previndex = 0;
                div_ticker = 0;
                sumrow = cell(1, 8);
                        
                for k=1:length(indexes)
                    if isequal(previndex, indexes(k))
                        div_ticker = div_ticker+1;
                        PCEsum = PCEsum+PCE(k);
                        FFsum = FFsum+FF(k);
                        Iscsum = Iscsum+Isc(k);
                        Vocsum = Vocsum+Voc(k);
                        Hisum = Hisum+Hi(k);
                        Areasum = Areasum+Area(k);
                        Plampsum = Plampsum+Plamp(k);

                        if isequal(k, length(indexes))
                            if isequal(app.bothsweepsButton.Value, 1)
                                Hisum = Hisum*2;
                            end
                            sumrow(2:8) = {PCEsum/div_ticker, FFsum/div_ticker, Iscsum/div_ticker, Vocsum/div_ticker, Hisum/div_ticker, Areasum/div_ticker, Plampsum/div_ticker};
                            rarray(rowcount+1+previndex, 1:8) = sumrow;
                        end
                    else
                        if ~isequal(previndex, 0)
                            if isequal(app.bothsweepsButton.Value, 1)
                                Hisum = Hisum*2;
                            end
                            sumrow(2:8) = {PCEsum/div_ticker, FFsum/div_ticker, Iscsum/div_ticker, Vocsum/div_ticker, Hisum/div_ticker, Areasum/div_ticker, Plampsum/div_ticker};
                            rarray(rowcount+1+previndex, 1:8) = sumrow;
                        end
                        previndex = indexes(k);
                        full_resultname = strsplit(rarray{k,1}, '_');
                        sumrow(1) = full_resultname(1);
                        div_ticker = 1;
                        PCEsum = PCE(k);
                        FFsum = FF(k);
                        Iscsum = Isc(k);
                        Vocsum = Voc(k);
                        Hisum = Hi(k);
                        Areasum = Area(k);
                        Plampsum = Plamp(k);
                    end
                end

                app.UITable.Data = rarray;
            end

        end

        % Value changed function: PlotbestcurvesofeachtypeCheckBox
        function PlotbestcurvesofeachtypeCheckBoxValueChanged(app, event)
            value = app.PlotbestcurvesofeachtypeCheckBox.Value;
            if isequal(value, 1)
                app.IncludedarkJVCheckBox.Enable = 'on';
                app.ShowlegendinplotsCheckBox.Enable = 'on';
            elseif isequal(value, 0)
                app.IncludedarkJVCheckBox.Enable = 'off';
                app.ShowlegendinplotsCheckBox.Enable = 'off';
            end
        end

        % Menu selected function: ExporttocsvMenu
        function ExporttocsvMenuSelected(app, event)
            Table = array2table(app.UITable.Data, "VariableNames", {'Sample', 'PCE (%)', 'FF (%)', 'Isc (mA/cm2)', 'Voc (V)', 'Hysteresis (%)', 'Area (mm2)', 'Lamp Power (mW/cm2)'});
            destination = fullfile(app.rfilepath, 'Data_table.csv');

            writetable(Table, destination)
        end

        % Menu selected function: ExporttoxslxMenu
        function ExporttoxslxMenuSelected(app, event)
            Table = array2table(app.UITable.Data, "VariableNames", {'Sample', 'PCE (%)', 'FF (%)', 'Isc (mA/cm2)', 'Voc (V)', 'Hysteresis (%)', 'Area (mm2)', 'Lamp Power (mW/cm2)'});
            destination = fullfile(app.rfilepath, 'Data_table.xlsx');
            
            writetable(Table, destination)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {603, 603};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Resultcompilerv52Label = uilabel(app.LeftPanel);
            app.Resultcompilerv52Label.Text = 'Result compiler v5.2';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TablePlotting = uitab(app.TabGroup);
            app.TablePlotting.Title = 'Table & Plotting';
            app.DatatablePanel = uipanel(app.TablePlotting);
            app.DatatablePanel.Title = 'Data table';
            app.DisplayfullsamplenamesCheckBox = uicheckbox(app.DatatablePanel);
            app.DisplayfullsamplenamesCheckBox.Text = 'Display full sample names';
            app.CalculateaveragesattheendoftableCheckBox = uicheckbox(app.DatatablePanel);
            app.CalculateaveragesattheendoftableCheckBox.Text = 'Calculate averages at the end of table';
            app.IncluderesultsfromButtonGroup = uibuttongroup(app.DatatablePanel);
            app.IncluderesultsfromButtonGroup.Title = 'Include results from';
            app.sweep1Button = uiradiobutton(app.IncluderesultsfromButtonGroup);
            app.sweep1Button.Text = 'sweep1';
            app.sweep1Button.Value = true;
            app.sweep2Button = uiradiobutton(app.IncluderesultsfromButtonGroup);
            app.sweep2Button.Text = 'sweep2';
            app.bothsweepsButton = uiradiobutton(app.IncluderesultsfromButtonGroup);
            app.bothsweepsButton.Text = 'both sweeps';
            app.BoxplotPanel = uipanel(app.TablePlotting);
            app.BoxplotPanel.Title = 'Boxplot';
            app.DrawboxplotsofPVparametersCheckBox = uicheckbox(app.BoxplotPanel);
            app.DrawboxplotsofPVparametersCheckBox.Text = 'Draw boxplots of PV parameters';
            app.IfIncludesweep2resultsisonLabel = uilabel(app.BoxplotPanel);
            app.IfIncludesweep2resultsisonLabel.Text = 'If you have selected "both sweeps" above';
            app.bothresultswillalsobeincludedinboxplotLabel = uilabel(app.BoxplotPanel);
            app.bothresultswillalsobeincludedinboxplotLabel.Text = 'both results will also be included in boxplot)';
            app.JVcurvesPanel = uipanel(app.TablePlotting);
            app.JVcurvesPanel.Title = 'J-V curves';
            app.PlotbestcurvesofeachtypeCheckBox = uicheckbox(app.JVcurvesPanel);
            app.PlotbestcurvesofeachtypeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotbestcurvesofeachtypeCheckBoxValueChanged, true);
            app.PlotbestcurvesofeachtypeCheckBox.Text = 'Plot best curves of each type';
            app.IncludedarkJVCheckBox = uicheckbox(app.JVcurvesPanel);
            app.IncludedarkJVCheckBox.Enable = 'off';
            app.IncludedarkJVCheckBox.Text = 'Include dark J-V';
            app.ShowlegendinplotsCheckBox = uicheckbox(app.JVcurvesPanel);
            app.ShowlegendinplotsCheckBox.Enable = 'off';
            app.ShowlegendinplotsCheckBox.Text = 'Show legend in plots';
            app.UpdateButton = uibutton(app.TablePlotting, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.SelectyourdatafileLabel = uilabel(app.TablePlotting);
            app.SelectyourdatafileLabel.Text = 'Select your data file:';
            app.OpenButton = uibutton(app.TablePlotting, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open...';
            app.Label = uilabel(app.TablePlotting);
            app.Label.Tooltip = {''};
            app.Label.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Sample'; 'PCE (%)'; 'FF (%)'; 'Jsc (mA/cm2)'; 'Voc (V)'; 'Hysteresis (%)'; 'Area (mm2)'; 'Plamp (mW/cm2)'};
            app.UITable.RowName = {};
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HSC_team_logo_lower_res.png');
            app.AuthorPaavoMkinenupdated21022025MATLAB2023bLabel = uilabel(app.RightPanel);
            app.AuthorPaavoMkinenupdated21022025MATLAB2023bLabel.Text = 'Author: Paavo Mäkinen (updated 21.02.2025, MATLAB 2023b)';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExporttocsvMenu = uimenu(app.ContextMenu);
            app.ExporttocsvMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttocsvMenuSelected, true);
            app.ExporttocsvMenu.Text = 'Export to .csv';
            app.ExporttoxslxMenu = uimenu(app.ContextMenu);
            app.ExporttoxslxMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoxslxMenuSelected, true);
            app.ExporttoxslxMenu.Text = 'Export to .xslx';
            app.CancelMenu = uimenu(app.ContextMenu);
            app.CancelMenu.Text = 'Cancel';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HSC_Result_compiler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end